classdef Capacitor_Designer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CapacitorDesignerUIFigure       matlab.ui.Figure
        BreakdownVoltageSpinner         matlab.ui.control.Spinner
        BreakdownVoltageSpinnerLabel    matlab.ui.control.Label
        CapacitanceSpinner              matlab.ui.control.Spinner
        CapacitanceSpinnerLabel         matlab.ui.control.Label
        CompareOutputsCheckBox          matlab.ui.control.CheckBox
        EndFrequencySpinner             matlab.ui.control.Spinner
        EndFrequencySpinnerLabel        matlab.ui.control.Label
        StartFreuqnecySpinner           matlab.ui.control.Spinner
        StartFreuqnecySpinnerLabel      matlab.ui.control.Label
        DielectricButtonGroup           matlab.ui.container.ButtonGroup
        OtherButton                     matlab.ui.control.RadioButton
        GlassButton                     matlab.ui.control.RadioButton
        MicaButton                      matlab.ui.control.RadioButton
        QuartzButton                    matlab.ui.control.RadioButton
        PolystyreneButton               matlab.ui.control.RadioButton
        TeflonButton                    matlab.ui.control.RadioButton
        AirButton                       matlab.ui.control.RadioButton
        ConductivitySpinner             matlab.ui.control.Spinner
        ConductivitySpinnerLabel        matlab.ui.control.Label
        DielectricStrengthSpinner       matlab.ui.control.Spinner
        DielectricStrengthSpinnerLabel  matlab.ui.control.Label
        PermittivitySpinner             matlab.ui.control.Spinner
        PermittivitySpinnerLabel        matlab.ui.control.Label
        PlateDistancemmSpinner          matlab.ui.control.Spinner
        PlateDistancemmSpinnerLabel     matlab.ui.control.Label
        PlateAreacm2Spinner             matlab.ui.control.Spinner
        PlateAreacm2SpinnerLabel        matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            area = app.PlateAreacm2Spinner.Value;
            distance = app.PlateDistancemmSpinner.Value;
            rel_permitivity = app.PermittivitySpinner.Value;
            dielectric_strength = app.DielectricStrengthSpinner.Value;
            conductivity = app.ConductivitySpinner.Value;

            permitivity = rel_permitivity*8.854*10^-12;

            frequency = {app.StartFreuqnecySpinner.Value, app.EndFrequencySpinner.Value};

            %Implement Spinners
            capacitance = permitivity*area*10^4/(distance*10^-3);
            bdv = dielectric_strength*distance*10^-3;
            app.CapacitanceSpinner.Value=capacitance;
            app.BreakdownVoltageSpinner.Value=bdv;

            %Toggle graphs on if not already on
            app.UIAxes.Visible="on";
            app.UIAxes_2.Visible="on";


            %Clear axes if compare checkbox is off, hold if on
            if (app.CompareOutputsCheckBox.Value == 0)
                cla(app.UIAxes, 'reset')
                cla(app.UIAxes_2, 'reset')
            else
                hold(app.UIAxes, 'on')
                hold(app.UIAxes_2, 'on')
            end

            %Define transfer function for reactance and plot its bode plots
            react_sys = tf([1./conductivity],[capacitance.*1./conductivity,1]);
            bodeplot(app.UIAxes, react_sys, frequency)
            title(app.UIAxes, "Reactance")

            %Define transfer function for leackage current and plot its bode plots
            leakage_sys = tf([capacitance.*1./conductivity,1],[1./conductivity]);
            bodeplot(app.UIAxes_2, leakage_sys, frequency)
            title(app.UIAxes_2, "Leakage Current")
        end

        % Selection changed function: DielectricButtonGroup
        function DielectricButtonGroupSelectionChanged(app, event)
            %Button that handles the radio button logic
            selectedButton = app.DielectricButtonGroup.SelectedObject;

            %If other button selected, skip function as not to mess with
            %user input
            if selectedButton == app.OtherButton
                return
            end

            %Array with values for each dielectric
            materiallist=[
                ["Air", 1.1, 3e6, 1.2e-12],
                ["Teflon", 2.1, 60e6, 10e-15],
                ["Polystyrene", 2.56, 20e6, 10e-17],
                ["Quartz", 3.8, 30e6, 10e-17],
                ["Mica", 5.4, 200e6, 10e-15],
                ["Glass", 10, 30e6, 10e-12],
            ];
            %Get the selected material
            material = selectedButton.Text;
            i=1;

            %Find the dielectic from the list and fill in the spinner, skip
            %if dielectric not found and set all values to 1
            while i<=length(materiallist)
                if materiallist(i,1) == material
                    app.PermittivitySpinner.Value = str2double(materiallist(i,2));
                    app.DielectricStrengthSpinner.Value = str2double(materiallist(i,3));
                    app.ConductivitySpinner.Value = str2double(materiallist(i,4));
                    return
                end
                i = i + 1;
            end
            app.PermittivitySpinner.Value = 1;
            app.DielectricStrengthSpinner.Value = 1;
            app.DielectricStrengthSpinner.Value = 1;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CapacitorDesignerUIFigure = uifigure('Visible', 'off');
            app.CapacitorDesignerUIFigure.Name = 'Capacitor Designer';
            app.UIAxes = uiaxes(app.CapacitorDesignerUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.CapacitorDesignerUIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.Visible = 'off';
            app.PlotButton = uibutton(app.CapacitorDesignerUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PlateAreacm2SpinnerLabel = uilabel(app.CapacitorDesignerUIFigure);
            app.PlateAreacm2SpinnerLabel.Text = 'Plate Area(cm^2)';
            app.PlateAreacm2Spinner = uispinner(app.CapacitorDesignerUIFigure);
            app.PlateAreacm2Spinner.Limits = [0.001 Inf];
            app.PlateAreacm2Spinner.Value = 1;
            app.PlateDistancemmSpinnerLabel = uilabel(app.CapacitorDesignerUIFigure);
            app.PlateDistancemmSpinnerLabel.Text = 'Plate Distance(mm)';
            app.PlateDistancemmSpinner = uispinner(app.CapacitorDesignerUIFigure);
            app.PlateDistancemmSpinner.Limits = [0.001 Inf];
            app.PlateDistancemmSpinner.Value = 1;
            app.PermittivitySpinnerLabel = uilabel(app.CapacitorDesignerUIFigure);
            app.PermittivitySpinnerLabel.Text = 'Permittivity';
            app.PermittivitySpinner = uispinner(app.CapacitorDesignerUIFigure);
            app.PermittivitySpinner.Limits = [1e-20 Inf];
            app.PermittivitySpinner.Value = 1;
            app.DielectricStrengthSpinnerLabel = uilabel(app.CapacitorDesignerUIFigure);
            app.DielectricStrengthSpinnerLabel.Text = 'Dielectric Strength';
            app.DielectricStrengthSpinner = uispinner(app.CapacitorDesignerUIFigure);
            app.DielectricStrengthSpinner.Limits = [1e-20 Inf];
            app.DielectricStrengthSpinner.Value = 1;
            app.ConductivitySpinnerLabel = uilabel(app.CapacitorDesignerUIFigure);
            app.ConductivitySpinnerLabel.Text = 'Conductivity';
            app.ConductivitySpinner = uispinner(app.CapacitorDesignerUIFigure);
            app.ConductivitySpinner.Limits = [1e-20 Inf];
            app.ConductivitySpinner.Value = 1;
            app.DielectricButtonGroup = uibuttongroup(app.CapacitorDesignerUIFigure);
            app.DielectricButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DielectricButtonGroupSelectionChanged, true);
            app.DielectricButtonGroup.Title = 'Dielectric';
            app.AirButton = uiradiobutton(app.DielectricButtonGroup);
            app.AirButton.Text = 'Air';
            app.TeflonButton = uiradiobutton(app.DielectricButtonGroup);
            app.TeflonButton.Text = 'Teflon';
            app.PolystyreneButton = uiradiobutton(app.DielectricButtonGroup);
            app.PolystyreneButton.Text = 'Polystyrene';
            app.QuartzButton = uiradiobutton(app.DielectricButtonGroup);
            app.QuartzButton.Text = 'Quartz';
            app.MicaButton = uiradiobutton(app.DielectricButtonGroup);
            app.MicaButton.Text = 'Mica';
            app.GlassButton = uiradiobutton(app.DielectricButtonGroup);
            app.GlassButton.Text = 'Glass';
            app.OtherButton = uiradiobutton(app.DielectricButtonGroup);
            app.OtherButton.Text = 'Other';
            app.OtherButton.Value = true;
            app.StartFreuqnecySpinnerLabel = uilabel(app.CapacitorDesignerUIFigure);
            app.StartFreuqnecySpinnerLabel.Text = 'Start Freuqnecy';
            app.StartFreuqnecySpinner = uispinner(app.CapacitorDesignerUIFigure);
            app.StartFreuqnecySpinner.Value = 1000000;
            app.EndFrequencySpinnerLabel = uilabel(app.CapacitorDesignerUIFigure);
            app.EndFrequencySpinnerLabel.Text = 'End Frequency';
            app.EndFrequencySpinner = uispinner(app.CapacitorDesignerUIFigure);
            app.EndFrequencySpinner.Value = 100000000;
            app.CompareOutputsCheckBox = uicheckbox(app.CapacitorDesignerUIFigure);
            app.CompareOutputsCheckBox.Text = 'Compare Outputs';
            app.CapacitanceSpinnerLabel = uilabel(app.CapacitorDesignerUIFigure);
            app.CapacitanceSpinnerLabel.Text = 'Capacitance';
            app.CapacitanceSpinner = uispinner(app.CapacitorDesignerUIFigure);
            app.CapacitanceSpinner.Editable = 'off';
            app.BreakdownVoltageSpinnerLabel = uilabel(app.CapacitorDesignerUIFigure);
            app.BreakdownVoltageSpinnerLabel.Text = 'Breakdown Voltage';
            app.BreakdownVoltageSpinner = uispinner(app.CapacitorDesignerUIFigure);
            app.BreakdownVoltageSpinner.Editable = 'off';
            % Show the figure after all components are created
            app.CapacitorDesignerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Capacitor_Designer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CapacitorDesignerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CapacitorDesignerUIFigure)
        end
    end
end